classdef LFP_analysis_GUI_desampled_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ControlDropDown               matlab.ui.control.DropDown
        ControlDropDownLabel          matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        BasicsTab                     matlab.ui.container.Tab
        sLabel_4                      matlab.ui.control.Label
        LengthEditField               matlab.ui.control.NumericEditField
        LengthEditFieldLabel          matlab.ui.control.Label
        NumericalvalueofdilutionsEditField  matlab.ui.control.EditField
        NumericalvalueofdilutionsEditFieldLabel  matlab.ui.control.Label
        sLabel                        matlab.ui.control.Label
        StimulusonsetEditField        matlab.ui.control.NumericEditField
        StimulusonsetEditFieldLabel   matlab.ui.control.Label
        NumberofdilutionsEditField    matlab.ui.control.NumericEditField
        NumberofdilutionsEditFieldLabel  matlab.ui.control.Label
        nnumORNnameEditField          matlab.ui.control.NumericEditField
        nnumORNnameEditFieldLabel     matlab.ui.control.Label
        PlottingTab                   matlab.ui.container.Tab
        yupperboundforLFPpeaksEditField  matlab.ui.control.NumericEditField
        yupperboundforLFPpeaksEditFieldLabel  matlab.ui.control.Label
        ylowerboundforLFP2ndplotEditField  matlab.ui.control.NumericEditField
        ylowerboundforLFP2ndplotEditFieldLabel  matlab.ui.control.Label
        ylowerboundforLFP1stplotEditField  matlab.ui.control.NumericEditField
        ylowerboundforLFP1stplotEditFieldLabel  matlab.ui.control.Label
        sLabel_3                      matlab.ui.control.Label
        sLabel_2                      matlab.ui.control.Label
        EndtimeEditField              matlab.ui.control.NumericEditField
        EndtimeEditFieldLabel         matlab.ui.control.Label
        StarttimeEditField            matlab.ui.control.NumericEditField
        StarttimeEditFieldLabel       matlab.ui.control.Label
        DilutionforLFPEditField       matlab.ui.control.NumericEditField
        DilutionforLFPEditFieldLabel  matlab.ui.control.Label
        StartButton                   matlab.ui.control.Button
        ClearButton                   matlab.ui.control.Button
        SavedataButton                matlab.ui.control.Button
        LoadLFPButton                 matlab.ui.control.Button
        UIAxes4                       matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        entire % Description
        data1 % Description
        data2 % Description
        data3 % Description
        data4 % Description
        data5 % Description
        data6 % Description
        data7 % Description
        data8 % Description
        data9 % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
Numerial_value_dilution=str2num(app.NumericalvalueofdilutionsEditField.Value)
%% Filter
fc=15
fs=5000
n_order=5
[d,e]=butter(n_order,fc/(fs/2));
for a=1:app.NumberofdilutionsEditField.Value
    for b=1:app.nnumORNnameEditField.Value
        LFP_single=squeeze(app.entire(a,b,:));
        LFP_filtered_pre(a,b,:)=filtfilt(d,e,LFP_single);
    end
end

for a=1:app.NumberofdilutionsEditField.Value
    LFP_singular=squeeze(LFP_filtered_pre(a,:,:));
    for b=1:app.LengthEditField.Value*5000/100
        LFP_filtered_pre2(a,:,b)=mean(LFP_singular(:,(b-1)*100+1:(b-1)*100+100),2);
    end
end

for a=1:app.NumberofdilutionsEditField.Value
    LFP_singular2=squeeze(LFP_filtered_pre2(a,:,:))
    LFP_filtered(a,:,:)=LFP_singular2-mean(LFP_singular2(:,(app.StimulusonsetEditField.Value-0.5)*50:app.StimulusonsetEditField.Value*50-1),2);
end
app.data1=LFP_filtered

%% Analysis (real peaks)
for a=1:app.NumberofdilutionsEditField.Value
    for b=1:app.nnumORNnameEditField.Value
        LFP_single=round(squeeze(LFP_filtered(a,b,:)),10);
        LFP_max_real(b,a)=min(LFP_single(50*app.StimulusonsetEditField.Value:50*(app.StimulusonsetEditField.Value+2)));
        timing=1/50:1/50:app.LengthEditField.Value;
        Order_in_traces_real(b,a)=find(LFP_single==LFP_max_real(b,a));
        peak_time_real(b,a)=timing(Order_in_traces_real(b,a));
    end
end
app.data2=abs(LFP_max_real)
app.data3=peak_time_real

%% Analysis (peaks at unified timing)
for a=1:app.NumberofdilutionsEditField.Value
    LFP_single_dilution=squeeze(LFP_filtered(a,:,:));
    LFP_average(a,:)=mean(LFP_single_dilution);
    timing=1/50:1/50:app.LengthEditField.Value;
    interm1=min(LFP_average(a,50*app.StimulusonsetEditField.Value:50*(app.StimulusonsetEditField.Value+2)));
    LFP_max_unified(1,a)=interm1(1);
    Order_in_traces_unified(1,a)=find(LFP_average(a,:)==LFP_max_unified(1,a));
    peak_time_unified(1,a)=timing(Order_in_traces_unified(1,a));
    LFP_sem(a,:)=std(LFP_single_dilution)./sqrt(app.nnumORNnameEditField.Value);
    LFP_sem_plot(1,a)=LFP_sem(a,Order_in_traces_unified(1,a))
    LFP_max_unified_save(:,a)=LFP_single_dilution(:,Order_in_traces_unified(1,a))
    clear interm1
end


decision=strcmp('Yes',app.ControlDropDown.Value)
if decision==1
    dilu_value=2
else
    dilu_value=1
end

app.data4=LFP_average;
app.data5=abs(LFP_max_unified);
app.data6=peak_time_unified;
app.data7=LFP_sem;
app.data8=LFP_sem_plot(1,dilu_value:app.NumberofdilutionsEditField.Value);
app.data9=abs(LFP_max_unified_save)

%% Plots
for a=1:app.NumberofdilutionsEditField.Value
    plot(app.UIAxes,app.StarttimeEditField.Value:1/50:app.EndtimeEditField.Value,LFP_average(a,app.StarttimeEditField.Value*50:app.EndtimeEditField.Value*50),'Color','k')
    hold(app.UIAxes,'on')
end

plot(app.UIAxes2,app.StarttimeEditField.Value:1/50:app.EndtimeEditField.Value,LFP_average(app.DilutionforLFPEditField.Value,app.StarttimeEditField.Value*50:app.EndtimeEditField.Value*50),'Color','k')
hold(app.UIAxes2,'on')
plot(app.UIAxes2,app.StarttimeEditField.Value:1/50:app.EndtimeEditField.Value,LFP_average(app.DilutionforLFPEditField.Value,app.StarttimeEditField.Value*50:app.EndtimeEditField.Value*50)+LFP_sem(app.DilutionforLFPEditField.Value,app.StarttimeEditField.Value*50:app.EndtimeEditField.Value*50),'Color','k')
hold(app.UIAxes2,'on')
plot(app.UIAxes2,app.StarttimeEditField.Value:1/50:app.EndtimeEditField.Value,LFP_average(app.DilutionforLFPEditField.Value,app.StarttimeEditField.Value*50:app.EndtimeEditField.Value*50)-LFP_sem(app.DilutionforLFPEditField.Value,app.StarttimeEditField.Value*50:app.EndtimeEditField.Value*50),'Color','k')

f1=figure
ax1=subplot(3,2,1)
for a=1:app.NumberofdilutionsEditField.Value
    plot(app.StarttimeEditField.Value:1/50:app.EndtimeEditField.Value,LFP_average(a,app.StarttimeEditField.Value*50:app.EndtimeEditField.Value*50),'Color','k')
    hold on
end
title('LFP across dilutions')
ylim_factor1=round((min(LFP_max_unified)-4)/2)
ylim_factor2=round((max(max(LFP_average,[],2))+4)/2)
if app.ylowerboundforLFP1stplotEditField.Value==0
    ylim([ylim_factor1*2 4])
    yticks([ylim_factor1*2 0 4])
else
    ylim([app.ylowerboundforLFP1stplotEditField.Value 4])
    yticks([app.ylowerboundforLFP1stplotEditField.Value 0 4])
end
xlim([app.StarttimeEditField.Value app.EndtimeEditField.Value])
xticks([app.StarttimeEditField.Value app.EndtimeEditField.Value])
box off

ax2=subplot(3,2,2)
plot(app.StarttimeEditField.Value:1/50:app.EndtimeEditField.Value,LFP_average(app.DilutionforLFPEditField.Value,app.StarttimeEditField.Value*50:app.EndtimeEditField.Value*50),'Color','k')
hold on
plot(app.StarttimeEditField.Value:1/50:app.EndtimeEditField.Value,LFP_average(app.DilutionforLFPEditField.Value,app.StarttimeEditField.Value*50:app.EndtimeEditField.Value*50)+LFP_sem(app.DilutionforLFPEditField.Value,app.StarttimeEditField.Value*50:app.EndtimeEditField.Value*50),'Color','k')
hold on
plot(app.StarttimeEditField.Value:1/50:app.EndtimeEditField.Value,LFP_average(app.DilutionforLFPEditField.Value,app.StarttimeEditField.Value*50:app.EndtimeEditField.Value*50)-LFP_sem(app.DilutionforLFPEditField.Value,app.StarttimeEditField.Value*50:app.EndtimeEditField.Value*50),'Color','k')
title('LFP with sem at chosen dilution')
if app.ylowerboundforLFP2ndplotEditField.Value==0
    ylim([ylim_factor1*2 4])
    yticks([ylim_factor1*2 0 4])
else
    ylim([app.ylowerboundforLFP2ndplotEditField.Value 4])
    yticks([app.ylowerboundforLFP2ndplotEditField.Value 0 4])
end
xlim([app.StarttimeEditField.Value app.EndtimeEditField.Value])
xticks([app.StarttimeEditField.Value app.EndtimeEditField.Value])
box off

ax3=subplot(3,2,3)
mean_peak_dendrite_ORNname=round(abs(LFP_max_unified(:,dilu_value:app.NumberofdilutionsEditField.Value)),4)
dif_value_dendrite_ORNname=max(mean_peak_dendrite_ORNname)/2-mean_peak_dendrite_ORNname
dif_value_dendrite_ORNname(dif_value_dendrite_ORNname<0)=nan
sorted_value_dendrite_ORNname=sort(dif_value_dendrite_ORNname)
original_back_dendrite_ORNname=round(max(mean_peak_dendrite_ORNname)/2-sorted_value_dendrite_ORNname(1),4)
order_dendrite=find(mean_peak_dendrite_ORNname==original_back_dendrite_ORNname)
dilution_50_dendrite=Numerial_value_dilution(order_dendrite)

hill_fit=@(v,x_data) (v(1)*x_data.^v(3))./((v(2)^v(3))+(x_data.^v(3)))
x_data=Numerial_value_dilution
y_data_dendrite=mean_peak_dendrite_ORNname
v0_dendrite=[max(mean_peak_dendrite_ORNname) dilution_50_dendrite 0.3] % maxResponse: maximum response, dilution_50: the dilution that reaches 50% max response, increase the third parameter by 0.1 if curve doesn't fit well
V_dendrite=lsqcurvefit(hill_fit,v0_dendrite,x_data,y_data_dendrite)
finer_x=linspace(min(x_data),max(x_data),6000)

errorbar(x_data,y_data_dendrite,LFP_sem_plot(dilu_value:app.NumberofdilutionsEditField.Value),'Marker','.','LineStyle','none','Color','k','MarkerSize',18)
hold on
plot(finer_x,hill_fit(V_dendrite,finer_x),'Color','k')
xticks(Numerial_value_dilution)
title('LFP with errorbar (unified)')
if app.yupperboundforLFPpeaksEditField.Value==0
    ylim([0 abs(ylim_factor1*2)])
    yticks([0 abs(ylim_factor1*2)])
else
    ylim([0 app.yupperboundforLFPpeaksEditField.Value])
    yticks([0 app.yupperboundforLFPpeaksEditField.Value])
end
set(gca,'xscale','log')
axis square

errorbar(app.UIAxes3,x_data,y_data_dendrite,LFP_sem_plot(dilu_value:app.NumberofdilutionsEditField.Value),'Marker','.','LineStyle','none','Color','k','MarkerSize',18)
hold(app.UIAxes3,'on')
plot(app.UIAxes3,finer_x,hill_fit(V_dendrite,finer_x),'Color','k')
set(app.UIAxes3,'xticklabels',{})
set(app.UIAxes3,'xscale','log')

ax4=subplot(3,2,4)
mean_peak_dendrite_ORNname=round(abs(mean(LFP_max_real(:,dilu_value:app.NumberofdilutionsEditField.Value))),4)
dif_value_dendrite_ORNname=max(mean_peak_dendrite_ORNname)/2-mean_peak_dendrite_ORNname
dif_value_dendrite_ORNname(dif_value_dendrite_ORNname<0)=nan
sorted_value_dendrite_ORNname=sort(dif_value_dendrite_ORNname)
original_back_dendrite_ORNname=round(max(mean_peak_dendrite_ORNname)/2-sorted_value_dendrite_ORNname(1),4)
order_dendrite=find(mean_peak_dendrite_ORNname==original_back_dendrite_ORNname)
dilution_50_dendrite=Numerial_value_dilution(order_dendrite)

hill_fit=@(v,x_data) (v(1)*x_data.^v(3))./((v(2)^v(3))+(x_data.^v(3)))
x_data=Numerial_value_dilution
y_data_dendrite=mean_peak_dendrite_ORNname
v0_dendrite=[max(mean_peak_dendrite_ORNname) dilution_50_dendrite 0.3] % maxResponse: maximum response, dilution_50: the dilution that reaches 50% max response, increase the third parameter by 0.1 if curve doesn't fit well
V_dendrite=lsqcurvefit(hill_fit,v0_dendrite,x_data,y_data_dendrite)
finer_x=linspace(min(x_data),max(x_data),6000)

errorbar(x_data,y_data_dendrite,std(LFP_max_real(:,dilu_value:app.NumberofdilutionsEditField.Value))./sqrt(app.nnumORNnameEditField.Value),'Marker','.','LineStyle','none','Color','k','MarkerSize',18)
hold on
plot(finer_x,hill_fit(V_dendrite,finer_x),'Color','k')
xticks(Numerial_value_dilution)
title('LFP with errorbar (real peaks)')
if app.yupperboundforLFPpeaksEditField.Value==0
    ylim([0 abs(ylim_factor1*2)])
    yticks([0 abs(ylim_factor1*2)])
else
    ylim([0 app.yupperboundforLFPpeaksEditField.Value])
    yticks([0 app.yupperboundforLFPpeaksEditField.Value])
end
set(gca,'xscale','log')
axis square

errorbar(app.UIAxes4,x_data,y_data_dendrite,std(LFP_max_real(:,dilu_value:app.NumberofdilutionsEditField.Value))./sqrt(app.nnumORNnameEditField.Value),'Marker','.','LineStyle','none','Color','k','MarkerSize',18)
hold(app.UIAxes4,'on')
plot(app.UIAxes4,finer_x,hill_fit(V_dendrite,finer_x),'Color','k')
set(app.UIAxes4,'xticklabels',{})
set(app.UIAxes4,'xscale','log')
        end

        % Button pushed function: LoadLFPButton
        function LoadLFPButtonPushed(app, event)
            uiopen
            app.entire=LFP;
        end

        % Button pushed function: SavedataButton
        function SavedataButtonPushed(app, event)
            LFP=app.data1;
            LFP_max_real=app.data2;
            peak_time_real=app.data3;
            LFP_average=app.data4;
            LFP_max_unified=app.data9;
            peak_time_unified=app.data6;
            LFP_sem=app.data7;
            LFP_sem_plot=app.data8;
            uisave({'LFP','LFP_max_real','peak_time_real','LFP_average','LFP_max_unified','peak_time_unified','LFP_sem','LFP_sem_plot'},'Analyzed LFP.mat')
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            cla(app.UIAxes)
            cla(app.UIAxes2)
            cla(app.UIAxes3)
            cla(app.UIAxes4)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'LFP across dilutions')
            xlabel(app.UIAxes, 'Time (s)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'LFP with sem at chosen dilution')
            xlabel(app.UIAxes2, 'Time (s)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'LFP with errorbar (unified)')
            xlabel(app.UIAxes3, 'Dilutions')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'LFP with errorbar (real peaks)')
            xlabel(app.UIAxes4, 'Dilutions')
            zlabel(app.UIAxes4, 'Z')
            app.LoadLFPButton = uibutton(app.UIFigure, 'push');
            app.LoadLFPButton.ButtonPushedFcn = createCallbackFcn(app, @LoadLFPButtonPushed, true);
            app.LoadLFPButton.Text = 'Load LFP';
            app.SavedataButton = uibutton(app.UIFigure, 'push');
            app.SavedataButton.ButtonPushedFcn = createCallbackFcn(app, @SavedataButtonPushed, true);
            app.SavedataButton.Text = 'Save data';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.BasicsTab = uitab(app.TabGroup);
            app.BasicsTab.Title = 'Basics';
            app.nnumORNnameEditFieldLabel = uilabel(app.BasicsTab);
            app.nnumORNnameEditFieldLabel.Text = 'n num ORNname';
            app.nnumORNnameEditField = uieditfield(app.BasicsTab, 'numeric');
            app.NumberofdilutionsEditFieldLabel = uilabel(app.BasicsTab);
            app.NumberofdilutionsEditFieldLabel.Text = 'Number of dilutions';
            app.NumberofdilutionsEditField = uieditfield(app.BasicsTab, 'numeric');
            app.StimulusonsetEditFieldLabel = uilabel(app.BasicsTab);
            app.StimulusonsetEditFieldLabel.Text = 'Stimulus onset';
            app.StimulusonsetEditField = uieditfield(app.BasicsTab, 'numeric');
            app.sLabel = uilabel(app.BasicsTab);
            app.sLabel.Text = '(s)';
            app.NumericalvalueofdilutionsEditFieldLabel = uilabel(app.BasicsTab);
            app.NumericalvalueofdilutionsEditFieldLabel.Text = 'Numerical value of dilutions';
            app.NumericalvalueofdilutionsEditField = uieditfield(app.BasicsTab, 'text');
            app.LengthEditFieldLabel = uilabel(app.BasicsTab);
            app.LengthEditFieldLabel.Text = 'Length';
            app.LengthEditField = uieditfield(app.BasicsTab, 'numeric');
            app.sLabel_4 = uilabel(app.BasicsTab);
            app.sLabel_4.Text = '(s)';
            app.PlottingTab = uitab(app.TabGroup);
            app.PlottingTab.Title = 'Plotting';
            app.DilutionforLFPEditFieldLabel = uilabel(app.PlottingTab);
            app.DilutionforLFPEditFieldLabel.Text = 'Dilution for LFP';
            app.DilutionforLFPEditField = uieditfield(app.PlottingTab, 'numeric');
            app.StarttimeEditFieldLabel = uilabel(app.PlottingTab);
            app.StarttimeEditFieldLabel.Text = 'Start time';
            app.StarttimeEditField = uieditfield(app.PlottingTab, 'numeric');
            app.EndtimeEditFieldLabel = uilabel(app.PlottingTab);
            app.EndtimeEditFieldLabel.Text = 'End time';
            app.EndtimeEditField = uieditfield(app.PlottingTab, 'numeric');
            app.sLabel_2 = uilabel(app.PlottingTab);
            app.sLabel_2.Text = '(s)';
            app.sLabel_3 = uilabel(app.PlottingTab);
            app.sLabel_3.Text = '(s)';
            app.ylowerboundforLFP1stplotEditFieldLabel = uilabel(app.PlottingTab);
            app.ylowerboundforLFP1stplotEditFieldLabel.Text = 'y lower bound for LFP (1st) plot';
            app.ylowerboundforLFP1stplotEditField = uieditfield(app.PlottingTab, 'numeric');
            app.ylowerboundforLFP2ndplotEditFieldLabel = uilabel(app.PlottingTab);
            app.ylowerboundforLFP2ndplotEditFieldLabel.Text = 'y lower bound for LFP (2nd) plot';
            app.ylowerboundforLFP2ndplotEditField = uieditfield(app.PlottingTab, 'numeric');
            app.yupperboundforLFPpeaksEditFieldLabel = uilabel(app.PlottingTab);
            app.yupperboundforLFPpeaksEditFieldLabel.Text = 'y upper bound for LFP peaks';
            app.yupperboundforLFPpeaksEditField = uieditfield(app.PlottingTab, 'numeric');
            app.ControlDropDownLabel = uilabel(app.UIFigure);
            app.ControlDropDownLabel.Text = 'Control';
            app.ControlDropDown = uidropdown(app.UIFigure);
            app.ControlDropDown.Items = {'Yes', 'No'};
            app.ControlDropDown.Value = 'Yes';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LFP_analysis_GUI_desampled_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end