classdef Spike_analysis_GUI_unified_peak_B < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RasterDropDown                  matlab.ui.control.DropDown
        RasterDropDownLabel             matlab.ui.control.Label
        ClearButton                     matlab.ui.control.Button
        ControlDropDown                 matlab.ui.control.DropDown
        ControlDropDownLabel            matlab.ui.control.Label
        SavedataButton                  matlab.ui.control.Button
        LoaddataButton                  matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        Basics1Tab                      matlab.ui.container.Tab
        NumericalvalueofdilutionEditField  matlab.ui.control.EditField
        NumericalvalueofdilutionEditFieldLabel  matlab.ui.control.Label
        numberofdilutionEditField_2     matlab.ui.control.NumericEditField
        numberofdilutionEditField_2Label  matlab.ui.control.Label
        nnumberdendriteEditField_2      matlab.ui.control.NumericEditField
        nnumberdendriteEditField_2Label  matlab.ui.control.Label
        nnumberORNnameEditField_2       matlab.ui.control.NumericEditField
        nnumberORNnameEditField_2Label  matlab.ui.control.Label
        Basics2Tab                      matlab.ui.container.Tab
        sLabel                          matlab.ui.control.Label
        msLabel_2                       matlab.ui.control.Label
        msLabel                         matlab.ui.control.Label
        StimulusonsetEditField_2        matlab.ui.control.NumericEditField
        StimulusonsetEditField_2Label   matlab.ui.control.Label
        LengthEditField_2               matlab.ui.control.NumericEditField
        LengthEditField_2Label          matlab.ui.control.Label
        BinEditField_2                  matlab.ui.control.NumericEditField
        BinEditField_2Label             matlab.ui.control.Label
        PlottingTab                     matlab.ui.container.Tab
        sLabel_2                        matlab.ui.control.Label
        yupperboundfordcurveEditField   matlab.ui.control.NumericEditField
        yupperboundfordcurveEditFieldLabel  matlab.ui.control.Label
        yupperboundforscatterEditField  matlab.ui.control.NumericEditField
        yupperboundforscatterEditFieldLabel  matlab.ui.control.Label
        yupperboundforPSTHEditField     matlab.ui.control.NumericEditField
        yupperboundforPSTHEditFieldLabel  matlab.ui.control.Label
        DilutionforPSTHEditField_2      matlab.ui.control.NumericEditField
        DilutionforPSTHEditField_2Label  matlab.ui.control.Label
        DilutionforrasterEditField_2    matlab.ui.control.NumericEditField
        DilutionforrasterEditField_2Label  matlab.ui.control.Label
        TimeafterstimulusforPSTHEditField_2  matlab.ui.control.NumericEditField
        TimeafterstimulusforPSTHEditField_2Label  matlab.ui.control.Label
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        entire % Description
        data1 % Description
        data2 % Description
        data3 % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoaddataButton
        function LoaddataButtonPushed(app, event)
            uiopen
            app.entire=tSPKB
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
Numerial_value_dilution=str2num(app.NumericalvalueofdilutionEditField.Value)
%% Spike analysis
for a=1:app.numberofdilutionEditField_2.Value % num of dilutions
    for b=1:app.nnumberORNnameEditField_2.Value % num of flies
        spike_all(a,b,:)=round(app.entire(a,b,:)./5);
    end
end
spike_all(spike_all==0)=nan;
app.data3=spike_all;

%% Spike counts
for a=1:app.numberofdilutionEditField_2.Value % num of dilutions
    for b=1:app.nnumberORNnameEditField_2.Value % num of flies
        h1=histogram(squeeze(spike_all(a,b,:)),'BinEdges',0:app.BinEditField_2.Value:app.LengthEditField_2.Value);
        counts(a,b,:)=h1.Values;
    end
end

%% Smooth
inte=5;
for aa=1:app.numberofdilutionEditField_2.Value; % num of dilutions
    trans=squeeze(counts(aa,:,:));
    for b=1:app.nnumberORNnameEditField_2.Value % num of flies
        for a=1:app.LengthEditField_2.Value/app.BinEditField_2.Value-(inte-1);
            counts_smt(aa,b,a+2)=mean(trans(b,(a-1)+(1:inte)),2);
        end;
    end;
end;

for aa=1:app.numberofdilutionEditField_2.Value; % num of dilutions
    trans=squeeze(counts(aa,:,:));
    for b=1:app.nnumberORNnameEditField_2.Value % num of flies
        counts_smt(aa,b,1:2)=trans(b,1:2);
        counts_smt(aa,b,app.LengthEditField_2.Value/app.BinEditField_2.Value-1:app.LengthEditField_2.Value/app.BinEditField_2.Value)=trans(b,app.LengthEditField_2.Value/app.BinEditField_2.Value-1:app.LengthEditField_2.Value/app.BinEditField_2.Value);
    end
end

%% PSTH for dendrites
time_spon=(app.StimulusonsetEditField_2.Value-1)+app.BinEditField_2.Value/1000

for a=1:app.numberofdilutionEditField_2.Value % num of dilutions
    sque_dendrite=squeeze(counts_smt(a,:,:))
    next_sque_dendrite(a,:,:)=(sque_dendrite-mean(sque_dendrite(:,time_spon*1000/app.BinEditField_2.Value:app.StimulusonsetEditField_2.Value*1000/app.BinEditField_2.Value),2))*(1000/app.BinEditField_2.Value);
end

value_dendrite=squeeze(counts_smt(app.DilutionforPSTHEditField_2.Value,:,:))
next_value_dendrite=(value_dendrite-mean(value_dendrite(:,time_spon*1000/app.BinEditField_2.Value:app.StimulusonsetEditField_2.Value*1000/app.BinEditField_2.Value),2))*(1000/app.BinEditField_2.Value)
psth_counts_dendrite=mean(next_value_dendrite(:,time_spon*1000/app.BinEditField_2.Value:(app.StimulusonsetEditField_2.Value+app.TimeafterstimulusforPSTHEditField_2.Value)*1000/app.BinEditField_2.Value))
sem_psth_counts_dendrite=std(next_value_dendrite(:,time_spon*1000/app.BinEditField_2.Value:(app.StimulusonsetEditField_2.Value+app.TimeafterstimulusforPSTHEditField_2.Value)*1000/app.BinEditField_2.Value),'omitnan')./sqrt(app.nnumberdendriteEditField_2.Value)
app.data1=next_sque_dendrite

%% Counts for dcurve
time_dcurve_1=app.StimulusonsetEditField_2.Value+0.1+app.BinEditField_2.Value/1000
time_dcurve_2=app.StimulusonsetEditField_2.Value+0.6
for a=1:app.numberofdilutionEditField_2.Value % num of dilutions
    for b=1:app.nnumberORNnameEditField_2.Value % num of flies
        value_dcurve(1,:)=squeeze(counts_smt(a,b,:))
        next_value_dcurve=value_dcurve-mean(value_dcurve(:,time_spon*1000/app.BinEditField_2.Value:app.StimulusonsetEditField_2.Value*1000/app.BinEditField_2.Value))
        counts_for_dcurve(a,b,:)=next_value_dcurve(:,time_dcurve_1*1000/app.BinEditField_2.Value:time_dcurve_2*1000/app.BinEditField_2.Value)
    end
end

%% PSTH plots
f1=figure
ax1=subplot(3,2,1)
set(gcf,'renderer','Painters')
plot(1:size(psth_counts_dendrite,2),psth_counts_dendrite,'Color','k')
hold on
plot(1:size(psth_counts_dendrite,2),psth_counts_dendrite+sem_psth_counts_dendrite,'Color','k')
hold on
plot(1:size(psth_counts_dendrite,2),psth_counts_dendrite-sem_psth_counts_dendrite,'Color','k')
title('PSTH')
xlim([1 size(psth_counts_dendrite,2)])
xticks([1*1000/app.BinEditField_2.Value 1.3*1000/app.BinEditField_2.Value])
ylim_factor=round((max(psth_counts_dendrite)+20)/10)
if app.yupperboundforPSTHEditField.Value==0
    yticks([-20 0 ylim_factor*10])
    ylim([-20 ylim_factor*10])
else
    yticks([-20 0 app.yupperboundforPSTHEditField.Value])
    ylim([-20 app.yupperboundforPSTHEditField.Value])
end
ax1.Position(3)=0.2
box off
axis square

plot(app.UIAxes,0:(app.StimulusonsetEditField_2.Value+app.TimeafterstimulusforPSTHEditField_2.Value)*1000/app.BinEditField_2.Value-time_spon*1000/app.BinEditField_2.Value,psth_counts_dendrite,'Color','k')
hold(app.UIAxes,'on')
plot(app.UIAxes,0:(app.StimulusonsetEditField_2.Value+app.TimeafterstimulusforPSTHEditField_2.Value)*1000/app.BinEditField_2.Value-time_spon*1000/app.BinEditField_2.Value,psth_counts_dendrite+sem_psth_counts_dendrite,'Color','k')
hold(app.UIAxes,'on')
plot(app.UIAxes,0:(app.StimulusonsetEditField_2.Value+app.TimeafterstimulusforPSTHEditField_2.Value)*1000/app.BinEditField_2.Value-time_spon*1000/app.BinEditField_2.Value,psth_counts_dendrite-sem_psth_counts_dendrite,'Color','k')
set(app.UIAxes,'xticklabels',{})

%% Raster
Choice=strcmp('Yes',app.RasterDropDown.Value)
if app.nnumberORNnameEditField_2.Value<14 && Choice==1
    f2=figure
    ax3=subplot(2,2,1)
    set(gcf,'renderer','Painters')
    spike_special_dendrite=spike_all;
    spike_special_dendrite(spike_special_dendrite<app.StimulusonsetEditField_2.Value*1000-1000)=nan;
    spike_special_dendrite(spike_special_dendrite>(app.StimulusonsetEditField_2.Value+app.TimeafterstimulusforPSTHEditField_2.Value)*1000)=nan;
    spike_raster_dendrite=squeeze(spike_special_dendrite(app.DilutionforrasterEditField_2.Value,:,:));
    for a=1:app.nnumberdendriteEditField_2.Value % num of flies for dendrite
        plot(spike_raster_dendrite(a,:),a,'Marker','|','LineStyle','none','Color','k')
        hold on
    end
    ax3.Position(4)=0.2/7*app.nnumberORNnameEditField_2.Value % height
    ax3.Position(3)=0.4 % width
    ylim([0 app.nnumberdendriteEditField_2.Value+1])
    yticklabels({})
    xlim([app.StimulusonsetEditField_2.Value*1000-1000 (app.StimulusonsetEditField_2.Value+app.TimeafterstimulusforPSTHEditField_2.Value)*1000])
    xticks([app.StimulusonsetEditField_2.Value*1000-1000 app.StimulusonsetEditField_2.Value*1000 (app.StimulusonsetEditField_2.Value+app.TimeafterstimulusforPSTHEditField_2.Value)*1000])
%     xticklabels({})
    title('Raster')
end

if app.nnumberORNnameEditField_2.Value<14 && Choice==1
    for a=1:app.nnumberdendriteEditField_2.Value % num of flies for dendrite
        plot(app.UIAxes2,spike_raster_dendrite(a,:),a,'Marker','|','LineStyle','none','Color','k')
        hold(app.UIAxes2,"on")
    end
    app.UIAxes2.Position(4)=120/6*app.nnumberORNnameEditField_2.Value
    set(app.UIAxes2,'ylim',[0 app.nnumberdendriteEditField_2.Value+1])
    set(app.UIAxes2,'yticklabels',{})
    set(app.UIAxes2,'xlim',[0 app.LengthEditField_2.Value])
    set(app.UIAxes2,'xticklabels',{})
end

%% Scatter plot
for a=1:app.numberofdilutionEditField_2.Value % num of dilutions
    sque_counts=squeeze(counts_for_dcurve(a,:,:));
    avg_counts=mean(sque_counts);
    temp_max=find(avg_counts==max(avg_counts));
    if size(temp_max,2)>1
        time_for_avg(1,a)=temp_max(1);
    else
        time_for_avg(1,a)=temp_max;
    end
    clear temp_max
end

for a=1:app.numberofdilutionEditField_2.Value % num of dilutions
    for b=1:app.nnumberORNnameEditField_2.Value % num of flies
        key=time_for_avg(1,a)
        max_peak(b,a)=counts_for_dcurve(a,b,key)*(1000/app.BinEditField_2.Value)
%         avg_peak(b,a)=mean(counts_for_dcurve(a,b,:),'omitnan')*20
    end
end

app.data2=max_peak

for a=1:app.numberofdilutionEditField_2.Value % num of dilutions
    colorlist{a}='k'
end

plotSpread(app.UIAxes3,max_peak,'distributionColors',colorlist)
hold(app.UIAxes3,'on')
plot(app.UIAxes3,mean(max_peak,'omitnan'),'Marker','+','Color','k','LineStyle','none')
set(app.UIAxes3,'xticklabels',{})

f3=figure
set(gcf,'renderer','Painters')
ax8=subplot(3,2,1)
plotSpread(max_peak,'distributionColors',colorlist)
hold on
plot(mean(max_peak,'omitnan'),'Marker','+','Color','k','LineStyle','none')
title('Scatter plot')
ylim_factor=round((max(max(max_peak))+20)/10)
if app.yupperboundforscatterEditField.Value==0
    yticks([0 ylim_factor*10])
    ylim([0 ylim_factor*10])
else
    yticks([0 app.yupperboundforscatterEditField.Value])
    ylim([0 app.yupperboundforscatterEditField.Value])
end
xticklabels({})
ax8.Position(3)=0.2
axis square
box off

%% Dosage Curve
decision=strcmp('Yes',app.ControlDropDown.Value)
if decision==1
    dilu_value=2
else
    dilu_value=1
end

mean_peak_dendrite_ORNname=round(mean(max_peak(:,dilu_value:app.numberofdilutionEditField_2.Value),'omitnan'))
dif_value_dendrite_ORNname=max(mean_peak_dendrite_ORNname)/2-mean_peak_dendrite_ORNname
dif_value_dendrite_ORNname(dif_value_dendrite_ORNname<0)=nan
sorted_value_dendrite_ORNname=sort(dif_value_dendrite_ORNname)
original_back_dendrite_ORNname=round(max(mean_peak_dendrite_ORNname)/2-sorted_value_dendrite_ORNname(1))
order_dendrite=find(mean_peak_dendrite_ORNname==original_back_dendrite_ORNname)
dilution_50_dendrite=Numerial_value_dilution(order_dendrite)
sem_peak_dendrite_ORNname=std(max_peak(:,dilu_value:app.numberofdilutionEditField_2.Value),'omitnan')./sqrt(app.nnumberdendriteEditField_2.Value)

hill_fit=@(v,x_data) (v(1)*x_data.^v(3))./((v(2)^v(3))+(x_data.^v(3)))
x_data=Numerial_value_dilution
y_data_dendrite=mean_peak_dendrite_ORNname
v0_dendrite=[max(mean_peak_dendrite_ORNname) dilution_50_dendrite 0.3] % maxResponse: maximum response, dilution_50: the dilution that reaches 50% max response, increase the third parameter by 0.1 if curve doesn't fit well
V_dendrite=lsqcurvefit(hill_fit,v0_dendrite,x_data,y_data_dendrite)
finer_x=linspace(min(x_data),max(x_data),6000)

errorbar(app.UIAxes4,x_data,y_data_dendrite,sem_peak_dendrite_ORNname,'Marker','.','LineStyle','none','Color','k','MarkerSize',18)
hold(app.UIAxes4,'on')
plot(app.UIAxes4,finer_x,hill_fit(V_dendrite,finer_x),'Color','k')
set(app.UIAxes4,'xscale','log')

f4=figure
set(gcf,'renderer','Painters')
ax4=subplot(3,2,1)
errorbar(x_data,y_data_dendrite,sem_peak_dendrite_ORNname,'Marker','.','LineStyle','none','Color','k','MarkerSize',18)
hold on
plot(finer_x,hill_fit(V_dendrite,finer_x),'Color','k')
xticklabels({})
title('Dosage curve')
ylim_factor=round((max(mean_peak_dendrite_ORNname)+20)/10)
if app.yupperboundfordcurveEditField.Value==0
    yticks([0 ylim_factor*10])
    ylim([0 ylim_factor*10])
else
    yticks([0 app.yupperboundfordcurveEditField.Value])
    ylim([0 app.yupperboundfordcurveEditField.Value])
end
set(gca,'xscale','log')
axis square
box off
        end

        % Button pushed function: SavedataButton
        function SavedataButtonPushed(app, event)
            psth=app.data1
            max_peaks=app.data2
            spike_times=app.data3
            uisave({'psth','max_peaks','spike_times'},'psth_and_peaks.mat')
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            cla(app.UIAxes)
            cla(app.UIAxes2)
            cla(app.UIAxes3)
            cla(app.UIAxes4)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'PSTH')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Spikes/s')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Raster')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Scatter plot')
            xlabel(app.UIAxes3, 'Dilutions')
            ylabel(app.UIAxes3, 'Spikes/s')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Dosage curve')
            xlabel(app.UIAxes4, 'Dilutions')
            ylabel(app.UIAxes4, 'Spikes/s')
            zlabel(app.UIAxes4, 'Z')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Basics1Tab = uitab(app.TabGroup);
            app.Basics1Tab.Title = 'Basics 1';
            app.nnumberORNnameEditField_2Label = uilabel(app.Basics1Tab);
            app.nnumberORNnameEditField_2Label.Text = 'n number ORNname';
            app.nnumberORNnameEditField_2 = uieditfield(app.Basics1Tab, 'numeric');
            app.nnumberdendriteEditField_2Label = uilabel(app.Basics1Tab);
            app.nnumberdendriteEditField_2Label.Text = 'n number dendrite';
            app.nnumberdendriteEditField_2 = uieditfield(app.Basics1Tab, 'numeric');
            app.numberofdilutionEditField_2Label = uilabel(app.Basics1Tab);
            app.numberofdilutionEditField_2Label.Text = 'number of dilution';
            app.numberofdilutionEditField_2 = uieditfield(app.Basics1Tab, 'numeric');
            app.NumericalvalueofdilutionEditFieldLabel = uilabel(app.Basics1Tab);
            app.NumericalvalueofdilutionEditFieldLabel.Text = 'Numerical value of dilution';
            app.NumericalvalueofdilutionEditField = uieditfield(app.Basics1Tab, 'text');
            app.Basics2Tab = uitab(app.TabGroup);
            app.Basics2Tab.Title = 'Basics 2';
            app.BinEditField_2Label = uilabel(app.Basics2Tab);
            app.BinEditField_2Label.Text = 'Bin';
            app.BinEditField_2 = uieditfield(app.Basics2Tab, 'numeric');
            app.LengthEditField_2Label = uilabel(app.Basics2Tab);
            app.LengthEditField_2Label.Text = 'Length';
            app.LengthEditField_2 = uieditfield(app.Basics2Tab, 'numeric');
            app.StimulusonsetEditField_2Label = uilabel(app.Basics2Tab);
            app.StimulusonsetEditField_2Label.Text = 'Stimulus onset';
            app.StimulusonsetEditField_2 = uieditfield(app.Basics2Tab, 'numeric');
            app.msLabel = uilabel(app.Basics2Tab);
            app.msLabel.Text = '(ms)';
            app.msLabel_2 = uilabel(app.Basics2Tab);
            app.msLabel_2.Text = '(ms)';
            app.sLabel = uilabel(app.Basics2Tab);
            app.sLabel.Text = '(s)';
            app.PlottingTab = uitab(app.TabGroup);
            app.PlottingTab.Title = 'Plotting';
            app.TimeafterstimulusforPSTHEditField_2Label = uilabel(app.PlottingTab);
            app.TimeafterstimulusforPSTHEditField_2Label.Text = 'Time after stimulus for PSTH';
            app.TimeafterstimulusforPSTHEditField_2 = uieditfield(app.PlottingTab, 'numeric');
            app.DilutionforrasterEditField_2Label = uilabel(app.PlottingTab);
            app.DilutionforrasterEditField_2Label.Text = 'Dilution for raster';
            app.DilutionforrasterEditField_2 = uieditfield(app.PlottingTab, 'numeric');
            app.DilutionforPSTHEditField_2Label = uilabel(app.PlottingTab);
            app.DilutionforPSTHEditField_2Label.Text = 'Dilution for PSTH';
            app.DilutionforPSTHEditField_2 = uieditfield(app.PlottingTab, 'numeric');
            app.yupperboundforPSTHEditFieldLabel = uilabel(app.PlottingTab);
            app.yupperboundforPSTHEditFieldLabel.Text = 'y upper bound for PSTH';
            app.yupperboundforPSTHEditField = uieditfield(app.PlottingTab, 'numeric');
            app.yupperboundforscatterEditFieldLabel = uilabel(app.PlottingTab);
            app.yupperboundforscatterEditFieldLabel.Text = 'y upper bound for scatter';
            app.yupperboundforscatterEditField = uieditfield(app.PlottingTab, 'numeric');
            app.yupperboundfordcurveEditFieldLabel = uilabel(app.PlottingTab);
            app.yupperboundfordcurveEditFieldLabel.Text = 'y upper bound for dcurve';
            app.yupperboundfordcurveEditField = uieditfield(app.PlottingTab, 'numeric');
            app.sLabel_2 = uilabel(app.PlottingTab);
            app.sLabel_2.Text = '(s)';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.LoaddataButton = uibutton(app.UIFigure, 'push');
            app.LoaddataButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddataButtonPushed, true);
            app.LoaddataButton.Text = 'Load data';
            app.SavedataButton = uibutton(app.UIFigure, 'push');
            app.SavedataButton.ButtonPushedFcn = createCallbackFcn(app, @SavedataButtonPushed, true);
            app.SavedataButton.Text = 'Save data';
            app.ControlDropDownLabel = uilabel(app.UIFigure);
            app.ControlDropDownLabel.Text = 'Control';
            app.ControlDropDown = uidropdown(app.UIFigure);
            app.ControlDropDown.Items = {'Yes', 'No'};
            app.ControlDropDown.Value = 'Yes';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.RasterDropDownLabel = uilabel(app.UIFigure);
            app.RasterDropDownLabel.Text = 'Raster';
            app.RasterDropDown = uidropdown(app.UIFigure);
            app.RasterDropDown.Items = {'Yes', 'No'};
            app.RasterDropDown.Value = 'Yes';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Spike_analysis_GUI_unified_peak_B

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end