classdef Spike_sorting_prep < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        LoadfolderButton              matlab.ui.control.Button
        SpikesortingpreparationLabel  matlab.ui.control.Label
        SaveLFPButton                 matlab.ui.control.Button
        SavespikesButton              matlab.ui.control.Button
        StartButton                   matlab.ui.control.Button
        Defalut00002Label             matlab.ui.control.Label
        DeltatEditField               matlab.ui.control.NumericEditField
        DeltatEditFieldLabel          matlab.ui.control.Label
        numberofdilutionsEditField    matlab.ui.control.NumericEditField
        numberofdilutionsEditFieldLabel  matlab.ui.control.Label
        nnumberEditField              matlab.ui.control.NumericEditField
        nnumberEditFieldLabel         matlab.ui.control.Label
    end

    
    properties (Access = public)
        path % Description
        Data1 % Description
        Data2 % Description
        Data3 % Description
        Data4 % Description
        Data5 % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
%% Real prep
cd(app.path)
list={'IN 0','IN 1'}
for a=1:app.nnumberEditField.Value % n_num
    for b=1:app.numberofdilutionsEditField.Value % num of dilutions
        c=1:9;
        assess=ismember(a,c);
        if assess==1
            eval(["name=[num2str(0) num2str(a) '_' num2str(0) num2str(b) '.abf'];"])
        else
            eval(["name=[num2str(a) '_' num2str(0) num2str(b) '.abf'];"])
        end
        eval(["raw_spike=abfload(name,'channels',list);"])
        eval(['spike_' num2str(a) '(:,b)=raw_spike(:,2);'])
        eval(['LFP_' num2str(a) '(:,b)=raw_spike(:,1);'])
    end
end

for a=1:app.nnumberEditField.Value % n_num
    eval(['ORN(:,a,:)=transpose(spike_' num2str(a) ');'])
    eval(['PID(:,a,:)=transpose(spike_' num2str(a) ');'])
    eval(['LFP(:,a,:)=transpose(LFP_' num2str(a) ');'])
end
deltat=app.DeltatEditField.Value;
stimsignal=repelem(1,1,size(ORN,3));
app.Data1=ORN;
app.Data2=PID;
app.Data3=deltat;
app.Data4=stimsignal;
app.Data5=LFP;
        end

        % Button pushed function: LoadfolderButton
        function LoadfolderButtonPushed(app, event)
            app.path=uigetdir
        end

        % Button pushed function: SavespikesButton
        function SavespikesButtonPushed(app, event)
            ORN=app.Data1;
            PID=app.Data2;
            deltat=app.Data3;
            stimsignal=app.Data4;
            uisave({'ORN','PID','deltat','stimsignal'},'Spikes.mat')
        end

        % Button pushed function: SaveLFPButton
        function SaveLFPButtonPushed(app, event)
            LFP=app.Data5;
            uisave('LFP','LFP.mat')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.nnumberEditFieldLabel = uilabel(app.UIFigure);
            app.nnumberEditFieldLabel.Text = 'n number';
            app.nnumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.numberofdilutionsEditFieldLabel = uilabel(app.UIFigure);
            app.numberofdilutionsEditFieldLabel.Text = 'number of dilutions';
            app.numberofdilutionsEditField = uieditfield(app.UIFigure, 'numeric');
            app.DeltatEditFieldLabel = uilabel(app.UIFigure);
            app.DeltatEditFieldLabel.Text = 'Delta t';
            app.DeltatEditField = uieditfield(app.UIFigure, 'numeric');
            app.Defalut00002Label = uilabel(app.UIFigure);
            app.Defalut00002Label.Text = 'Defalut: 0.0002';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SavespikesButton = uibutton(app.UIFigure, 'push');
            app.SavespikesButton.ButtonPushedFcn = createCallbackFcn(app, @SavespikesButtonPushed, true);
            app.SavespikesButton.Text = 'Save spikes';
            app.SaveLFPButton = uibutton(app.UIFigure, 'push');
            app.SaveLFPButton.ButtonPushedFcn = createCallbackFcn(app, @SaveLFPButtonPushed, true);
            app.SaveLFPButton.Text = 'Save LFP';
            app.SpikesortingpreparationLabel = uilabel(app.UIFigure);
            app.SpikesortingpreparationLabel.Text = 'Spike sorting preparation';
            app.LoadfolderButton = uibutton(app.UIFigure, 'push');
            app.LoadfolderButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfolderButtonPushed, true);
            app.LoadfolderButton.Text = 'Load folder';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Spike_sorting_prep

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end